classdef WebCamPixelater < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        DropDown              matlab.ui.control.DropDown
        PixelaterEffectLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Webcam
        Play = true
    end

    methods (Access = private)
        function processVideo( app )
            while app.Play
                img = snapshot( app.Webcam );
                img = fliplr( img );
                img = imeffects( img, app.DropDown.Value );
                app.UIAxes.Children.CData = img;
                drawnow limitrate
            end
            delete( app )
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui( app.UIFigure, "center" )
            try
                camlist = webcamlist;
            catch ME
                app.DropDown.Enable = "off";
                app.Play = false;
                msg = "Web Cam Pixelater requires the <html><a href=""" + ...
                    "matlab:matlab.internal.addons.launchers.showExplorer(" + ...
                    "'ErrorRecovery','identifier','USBWEBCAM','focused','webcamlist')" + ...
                    """>MATLAB Support Package for USB Webcams</a></html>." + newline + ...
                    newline + "Please close the app, install the support package, and try again.";
                selection = uiconfirm( app.UIFigure, msg, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            try
                % Setup Webcam
                opts = [camlist; "Close"];
                msg = "Please choose from the following options:";
                selection = uiconfirm( app.UIFigure, msg, ...
                    "Select Web Cam", Options=opts );
                if (selection == opts(end))
                    delete( app )
                    return
                end

                % Setup webcam axes
                app.Webcam = webcam( selection );
                img = snapshot( app.Webcam );
                img = fliplr( img );
                img = imeffects( img, "none" ); % test function
                imshow( img, Parent=app.UIAxes )
                axis( app.UIAxes, "image")
                disableDefaultInteractivity( app.UIAxes )
                app.UIAxes.Toolbar.Visible = "off";
                app.processVideo(); % start running

            catch ME
                app.DropDown.Enable = "off";
                app.Play = false;
                msg = string( ME.message ) + newline + newline + ...
                    "Web Cam Pixelater requires 'imeffects' from the <html><a href=" + ...
                    "'https://www.mathworks.com/matlabcentral/fileexchange/87452'" + ...
                    ">Pixels to Pictures Supplemental Material</a></html>." + newline + ...
                    newline + "Please close the app, ensure this file is available, and try again.";
                selection = uiconfirm( app.UIFigure, msg, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.Play
                % Stop loop before exiting
                app.Play = false;
            else
                delete( app )
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Web Cam Pixelater';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.PixelaterEffectLabel = uilabel(app.GridLayout);
            app.PixelaterEffectLabel.Text = 'Pixelater Effect:';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'none', 'barrel', 'blur', 'edge', 'grayscale', 'negative', 'neon', 'pencil', 'pincushion', 'pixelate', 'quantize', 'wave'};
            app.DropDown.Value = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebCamPixelater

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end