classdef WebCamPixelater < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        RunButton       matlab.ui.control.Button
        SaveButton      matlab.ui.control.Button
        PixelaterLabel  matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        UIAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Webcam
        Play = false
        Save = false
        Close = false
    end

    methods (Access = private)

        function processVideo( app )
            n = 60; % number of frames to buffer (~3 seconds)
            img = app.UIAxes.Children.CData;
            frames = zeros( [size(img) n], class(img) );
            idx = round( linspace( 1, n, 10 ) ); % 10 frames for "ghost"

            try
                while app.Play
                    img = snapshot( app.Webcam );
                    img = fliplr( img );
                    frames = circshift( frames, 1, 4 );
                    frames(:,:,:,1) = img; % store current frame
                    if (app.DropDown.Value == "ghost")
                        img = imeffects( frames(:,:,:,idx), "ghost" );
                    elseif (app.DropDown.Value == "hologram")
                        % separate images by two frames (~0.1 seconds)
                        img = imeffects( frames(:,:,:,[1 3]), "hologram" );
                    else
                        img = imeffects( img, app.DropDown.Value );
                    end
                    app.UIAxes.Children.CData = img;
                    drawnow limitrate; % ~20 fps
                end

            catch ME
                selection = uiconfirm( app.UIFigure, ME.message, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            if app.Save
                saveImage( app );
                app.RunButtonPushed(); % restart video feed loop
            elseif app.Close
                delete( app )
            end
        end

        function saveImage( app )
            % Prompt user for save location
            fileName = "pixelater_" + app.DropDown.Value + ".png";
            [fileName,filePath] = uiputfile( fileName ); % default value
            focus( app.UIFigure ) % restore app focus

            img = fliplr( app.UIAxes.Children.CData ); % un-mirror
            if isequal( fileName, 0 )
                % User canceled file save, assign to workspace instead
                varName = "pix_" + string( datetime( "now", Format="HHmmss" ) );
                assignin( "base", varName, img )
            else
                fileName = fullfile( filePath, fileName );
                imwrite( img, fileName ) % save image
            end
            app.Save = false;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui( app.UIFigure, "center" )
            % assignin( "base", "app", app ) % debug
            try
                camlist = webcamlist;
            catch ME
                msg = "Web Cam Pixelater requires the <html><a href=""" + ...
                    "matlab:matlab.internal.addons.launchers.showExplorer(" + ...
                    "'ErrorRecovery','identifier','USBWEBCAM','focused','webcamlist')" + ...
                    """>MATLAB Support Package for USB Webcams</a></html>." + newline + ...
                    newline + "Please close the app, install the support package, and try again.";
                selection = uiconfirm( app.UIFigure, msg, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            try
                % Setup Webcam
                opts = [camlist; "Close"];
                msg = "Please choose from the following options:";
                selection = uiconfirm( app.UIFigure, msg, ...
                    "Select Web Cam", Options=opts );
                if (selection == opts(end))
                    delete( app );
                    return
                end

                % Setup axes and test imeffects call
                app.Webcam = webcam( selection );
                img = snapshot( app.Webcam );
                img = fliplr( img );
                img = imeffects( img, "none" );
                imshow( img, Parent=app.UIAxes )
                axis( app.UIAxes, "image")
                disableDefaultInteractivity( app.UIAxes )
                app.UIAxes.Toolbar.Visible = "off";

            catch ME
                msg = string( ME.message ) + newline + newline + ...
                    "Web Cam Pixelater requires files from the <html><a href=" + ...
                    "'https://www.mathworks.com/matlabcentral/fileexchange/87452'" + ...
                    ">Pixels to Pictures Supplemental Material</a></html>." + ...
                    newline + newline + "Please ensure these files are available, " + ...
                    "the 'P2P-Supplement.prj' project is open, and try again.";
                selection = uiconfirm( app.UIFigure, msg, "Error", ...
                    Interpreter="html", Options="Close", Icon="error" ); %#ok<NASGU>
                delete( app ); % after user confirms by returning output
                return
            end

            % Add "ghost" effect if release is R2024b or newer
            items = string( app.DropDown.Items );
            if ~isMATLABReleaseOlderThan( "R2024b" )
                items = [items(1), sort( ["ghost", items(2:(end-1))] ), items(end)];
            end

            % Add "gotham" effect if Computer Vision is available
            % ("custom" uses insertText, which also require CV Toolbox)
            hasCompVision = license( "test", "video_and_image_blockset" );
            if logical( hasCompVision )
                items = [items(1), sort( ["gotham", items(2:(end-1))] ), "custom"];
            end

            % Enable components and push run button
            app.DropDown.Items = items;
            app.DropDown.Enable = "on";
            app.RunButton.Enable = "on";
            app.SaveButton.Enable = "on";
            app.RunButtonPushed(); % start video feed loop
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.Play = ~app.Play; % toggle play status
            if app.Play
                app.RunButton.Text = "⏸️ Pause";
                processVideo( app ); % start video feed loop
            else
                app.RunButton.Text = "▶️ Play";
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.Play
                app.Save = true;  % trigger save when loop stops
                app.Play = false; % stop video feed loop
            else
                saveImage( app );
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Close = true; % trigger close when loop stops
            if app.Play
                app.Play = false; % stop loop before exiting
            else
                delete( app );
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Web Cam Pixelater';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'pixelater_24.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'none', 'barrel', 'blur', 'edge', 'grayscale', 'hologram', 'kaleidoscope', 'matrix', 'negative', 'neon', 'pencil', 'pincushion', 'pixelate', 'quantize', 'rc-pop', 'thermal', 'wave'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'none';
            app.PixelaterLabel = uilabel(app.GridLayout);
            app.PixelaterLabel.Text = 'Select Pixelater Effect:';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '💾 Save';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = '▶️ Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebCamPixelater

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end